classdef SkSt2023 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MessagePanel             matlab.ui.container.Panel
        Label_2                  matlab.ui.control.Label
        ControlsPanel            matlab.ui.container.Panel
        SaveButton               matlab.ui.control.Button
        FineTuneThresholdsLabel  matlab.ui.control.Label
        OrientationLabel         matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        BrainSlider              matlab.ui.control.Slider
        BrainSliderLabel         matlab.ui.control.Label
        rightButton              matlab.ui.control.Button
        downButton               matlab.ui.control.Button
        leftButton               matlab.ui.control.Button
        upButton                 matlab.ui.control.Button
        SliceEditField           matlab.ui.control.NumericEditField
        SliceSlider              matlab.ui.control.Slider
        SliceSliderLabel         matlab.ui.control.Label
        LoadButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        SkullSlider              matlab.ui.control.Slider
        SkullSliderLabel         matlab.ui.control.Label
        ThresholdEditField       matlab.ui.control.NumericEditField
        InteractiveCheckBox      matlab.ui.control.CheckBox
        FileEditField            matlab.ui.control.EditField
        FileEditFieldLabel       matlab.ui.control.Label
        AxialImagePanel          matlab.ui.container.Panel
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        img_vol
        mask_vol
        full_mask_vol
        skull_vol
        image
        first
        last
        maxslice
        
        is_vol
    
    end

    methods (Access = private)
    
        function results = enableControls(app)
            app.FileEditField.Enable=1;
            app.InteractiveCheckBox.Enable=1;
            app.ThresholdEditField.Enable=1;
            app.SkullSlider.Enable=1;
            app.StartButton.Enable=1;
            app.LoadButton.Enable=1;
            app.SliceSlider.Enable=1;
            app.SliceEditField.Enable=1;
        end
        
        function results = disableControls(app)
            app.FileEditField.Enable=0;
            app.InteractiveCheckBox.Enable=0;
            app.ThresholdEditField.Enable=0;
            app.SkullSlider.Enable=0;
            app.StartButton.Enable=0;
            app.LoadButton.Enable=0;
            app.SliceSlider.Enable=0;
            app.SliceEditField.Enable=0;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.SliceSlider.Value;
            app.SliceEditField.Value = value;
             value = app.SkullSlider.Value;
            app.ThresholdEditField.Value = value;
             
            app.EditField.Value = app.BrainSlider.Value;;
        end

        % Value changed function: SkullSlider
        function SkullSliderValueChanged(app, event)
            value = app.SkullSlider.Value;
            app.ThresholdEditField.Value = value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            [file,path] = uigetfile({'*.tif';'*.jpg';'*.jpeg';'*.nii';'*.gz'; 'All Files (*.*)'});
            app.FileEditField.Value=[path,file];
            
            if isvector(app.FileEditField.Value)
                if(contains(app.FileEditField.Value,'nii'))
                    app.is_vol=1;
                    app.img_vol = niftiread(app.FileEditField.Value);
                    %app.img_vol=permute(app.img_vol,[1,3,2]);
                    [x,y,z,t]=size(app.img_vol);
                    app.SliceSlider.Limits=[1,z];
                    app.maxslice=z;
                    app.SliceSlider.Enable=1;
                    app.SliceSlider.Value = z/2;
                    drawnow;
                    app.full_mask_vol = zeros(size(app.img_vol));
                    app.mask_vol = zeros(size(app.img_vol));
                    app.skull_vol = zeros(size(app.img_vol));
                else
                    app.SliceSlider.Limits=[1,2];
                    app.maxslice=1;
                    app.SliceSlider.Value = 1;
                    app.SliceSlider.Enable=0;
                    app.is_vol=0;
                    app.image = imread(app.FileEditField.Value);
                    app.SliceSlider.Enable=0;
                    app.image = im2uint8(imadjust(app.image,stretchlim(app.image),[0,0.9]));
                    %app.img_vol=zeros(size(app.image));
                    [xi,yi,zi] = size(app.image);                    
                    if(zi>1)
                        app.image=rgb2gray(app.image)
                    end
                    app.img_vol=zeros(size(app.image));
                    app.img_vol(:,:,1) = app.image;
                    app.full_mask_vol = zeros(size(app.img_vol));
                    app.mask_vol = zeros(size(app.img_vol));
                    app.skull_vol = zeros(size(app.img_vol));
                end
                app.StartButton.Enable=1;
                SliceSliderValueChanged(app);
            end


        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            value = app.SliceSlider.Value;
            app.SliceEditField.Value = value;
            imshow(rescale(app.img_vol(:,:,app.SliceEditField.Value)), 'parent', app.UIAxes);
            [xx,yy,zz] = size(app.img_vol(:,:,app.SliceEditField.Value));
            if(~isempty(app.full_mask_vol))
                 img1=zeros(xx,yy,3);
                 imshow(app.full_mask_vol(:,:,app.SliceEditField.Value),[], 'parent', app.UIAxes2);
                 img1(:,:,1)=rescale(app.img_vol(:,:,app.SliceEditField.Value));
                 img1(:,:,2)=rescale(app.mask_vol(:,:,app.SliceEditField.Value))*0.3;
                 img1(:,:,3)=rescale(app.skull_vol(:,:,app.SliceEditField.Value))*0.3;
                 imshow(img1, 'parent', app.UIAxes3);
                 imshow(bitand(im2uint8(app.mask_vol(:,:,app.SliceEditField.Value)),im2uint8(rescale(app.img_vol(:,:,app.SliceEditField.Value)))), 'parent', app.UIAxes4);
            end
            
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          path = app.FileEditField.Value;
          
          disableControls(app);
          drawnow;
          
          if (app.InteractiveCheckBox.Value)
              app.first=app.SliceEditField.Value
              app.last=app.SliceEditField.Value 
          else
              app.first=1
              app.last=app.maxslice
          end
          
          
          if isvector(path)
                if(contains(path,'nii'))
                %    app.img_vol= niftiread(path);
                %    img_vol=permute(img_vol,[2,1,3]);  %for FLAIR
                %    app.img_vol=permute(app.img_vol,[1,3,2]);  %for T1W
                    [x,y,z,t]=size(app.img_vol);
                    
                        for i=app.first:app.last
                            disp("processing axial mask: " + i)
                            app.image = im2uint8(rescale(app.img_vol(:,:,i)));
                            app.image = im2uint8(imadjust(app.image,stretchlim(app.image),[0,0.9]));
                            app.image(:,:,2)=app.image(:,:,1);
                            app.image(:,:,3)=app.image(:,:,1);
                            [mask skull] = test5(app.image, app.ThresholdEditField.Value, app.EditField.Value); %work on 2nd ocuurance for centroid in test5.m
                            mask=imfill(mask,'holes');
                            oxoy=size(app.image);
                            ox = oxoy(1);
                            oy = oxoy(2);
                            mxmy = size(mask);
                            [mx]=mxmy(1);
                            [my]=mxmy(2);
                            if(sum(sum(mask))>0)
                                mask_cropped = mask(round((mx-ox)/2):round((mx-ox)/2+ox)-1,round((my-oy)/2):round((my-oy)/2+oy)-1);
                                mask_cropped = mask_cropped*250;
                            else
                                mask_cropped = zeros(x,y);
                            end
                            if(sum(sum(skull))>0)
                                skull_cropped = skull(round((mx-ox)/2):round((mx-ox)/2+ox)-1,round((my-oy)/2):round((my-oy)/2+oy)-1);
                                skull_cropped = skull_cropped*100;
                                else
                                    skull_cropped = zeros(x,y);
                            end
                            
                            app.mask_vol(:,:,i) = im2uint8(rescale(mask_cropped));
                            app.skull_vol(:,:,i)=im2uint8(rescale(skull_cropped)*0.5);
                            
                        end

                       % app.mask_vol=permute(app.mask_vol,[2,1,3]);  %for T1W
                        %img_vol=fliplr(img_vol);  %for T1W
                        if (~app.InteractiveCheckBox.Value)
                        
                            disp("Processing 3D mask volume.")
  
                            se3d=strel('sphere',2);
                            app.mask_vol = imopen(app.mask_vol,se3d);
                            app.mask_vol = imclose(app.mask_vol,se3d);
                            
                            app.skull_vol = imopen(app.skull_vol,se3d);
                            app.skull_vol = imclose(app.skull_vol,se3d);

                            CC = bwconncomp(app.mask_vol);
                            numPixels = cellfun(@numel,CC.PixelIdxList);
                            [biggest,idx] = max(numPixels);
                            app.mask_vol = zeros(size(app.mask_vol));
                            if(~isempty(idx))
                                app.mask_vol(CC.PixelIdxList{idx}) = 1;
                            end
 
                            se3d=strel('sphere',5);
                            app.mask_vol = imclose(app.mask_vol,se3d);
                            app.mask_vol = im2uint8(app.mask_vol);
                            pathf = replace(path,'.nii','_OUT.nii');
                            niftiwrite(app.mask_vol,pathf);
                          
                            disp("Writing 3D mask volume.")
                            app.full_mask_vol = im2uint8(rescale(bitor(im2uint8(rescale(app.skull_vol)*0.5),app.mask_vol)));
                            SliceSliderValueChanged(app);
                        else
                             disp("Processing 3D mask volume.")
                            brain_mask = app.mask_vol(:,:,i);
                            se=strel('disk',2);
                            brain_mask = imopen(brain_mask,se);
                            brain_mask = imclose(brain_mask,se);

                            se=strel('disk',5);
                            brain_mask = imclose(brain_mask,se);
                            brain_mask = im2uint8(brain_mask);
                            app.mask_vol(:,:,i) = brain_mask;
                            app.full_mask_vol = im2uint8(rescale(bitor(im2uint8(rescale(app.skull_vol)*0.5),im2uint8(app.mask_vol))));
                            SliceSliderValueChanged(app);
                        
                        end


               else
                    
                    %app.image(:,:,2)=app.image(:,:,1);
                    %app.image(:,:,3)=app.image(:,:,1);
                    [mask, skull] = test5(app.image, app.ThresholdEditField.Value, app.EditField.Value);
                    mask=imfill(mask,'holes');
                     oxoy=size(app.image);
                     [x,y,z,t]=size(app.img_vol);
                            ox = oxoy(1);
                            oy = oxoy(2);
                            mxmy = size(mask);
                            [mx]=mxmy(1);
                            [my]=mxmy(2);
                            if(sum(sum(mask))>0)
                                mask_cropped = mask(round((mx-ox)/2):round((mx-ox)/2+ox)-1,round((my-oy)/2):round((my-oy)/2+oy)-1);
                                mask_cropped = mask_cropped*250;
                            else
                                mask_cropped = zeros(x,y);
                            end
                            if(sum(sum(skull))>0)
                                skull_cropped = skull(round((mx-ox)/2):round((mx-ox)/2+ox)-1,round((my-oy)/2):round((my-oy)/2+oy)-1);
                                skull_cropped = skull_cropped*100;
                                else
                                    skull_cropped = zeros(x,y);
                            end
                            app.mask_vol(:,:,1) = im2uint8(rescale(mask_cropped));
                            app.skull_vol(:,:,1)=im2uint8(rescale(skull_cropped)*0.5);
                             disp("Processing 2D mask image.")
                            brain_mask = app.mask_vol(:,:,1);
                            se=strel('disk',2);
                            brain_mask = imopen(brain_mask,se);
                            brain_mask = imclose(brain_mask,se);

                            se=strel('disk',5);
                            brain_mask = imclose(brain_mask,se);
                            brain_mask = im2uint8(brain_mask);
                            app.mask_vol(:,:,1) = brain_mask;
                            app.full_mask_vol = im2uint8(rescale(bitor(im2uint8(rescale(app.skull_vol)*0.5),im2uint8(app.mask_vol))));
                            SliceSliderValueChanged(app);
                end
    
    
          else  
              
              %display error in path
              
          end
            
          enableControls(app);  


        end

        % Button pushed function: upButton
        function upButtonPushed(app, event)
            app.img_vol=permute(app.img_vol,[2,3,1]);
            app.mask_vol=permute(app.mask_vol,[2,3,1]);
            app.full_mask_vol=permute(app.full_mask_vol,[2,3,1]);
            app.skull_vol=permute(app.skull_vol,[2,3,1]);
            
            [x,y,z,t]=size(app.img_vol);
                    app.SliceSlider.Limits=[1,z];
                    app.maxslice=z;
                    app.SliceSlider.Enable=1;
                    app.SliceSlider.Value = z/2;
           SliceSliderValueChanged(app);
        end

        % Button pushed function: rightButton
        function rightButtonPushed(app, event)
            app.img_vol=permute(app.img_vol,[1,3,2]);
            app.mask_vol=permute(app.mask_vol,[1,3,2]);
            app.full_mask_vol=permute(app.full_mask_vol,[1,3,2]);
            app.skull_vol=permute(app.skull_vol,[1,3,2]);
            
            [x,y,z,t]=size(app.img_vol);
                    app.SliceSlider.Limits=[1,z];
                    app.maxslice=z;
                    app.SliceSlider.Enable=1;
                    app.SliceSlider.Value = z/2;
            SliceSliderValueChanged(app);
        end

        % Button pushed function: downButton
        function downButtonPushed(app, event)
            app.img_vol=permute(app.img_vol,[3,2,1]);
            app.mask_vol=permute(app.mask_vol,[3,2,1]);
            app.full_mask_vol=permute(app.full_mask_vol,[3,2,1]);
            app.skull_vol=permute(app.skull_vol,[3,2,1]);
            
            [x,y,z,t]=size(app.img_vol);
                    app.SliceSlider.Limits=[1,z];
                    app.maxslice=z;
                    app.SliceSlider.Enable=1;
                    app.SliceSlider.Value = z/2;
            SliceSliderValueChanged(app);
        end

        % Button pushed function: leftButton
        function leftButtonPushed(app, event)
            app.img_vol=permute(app.img_vol,[3,1,2]);
            app.mask_vol=permute(app.mask_vol,[3,1,2]);
            app.full_mask_vol=permute(app.full_mask_vol,[3,1,2]);
            app.skull_vol=permute(app.skull_vol,[3,1,2]);
            
            [x,y,z,t]=size(app.img_vol);
                    app.SliceSlider.Limits=[1,z];
                    app.maxslice=z;
                    app.SliceSlider.Enable=1;
                    app.SliceSlider.Value = z/2;
            SliceSliderValueChanged(app);
        end

        % Value changed function: BrainSlider
        function BrainSliderValueChanged(app, event)
            value = app.BrainSlider.Value;
            app.EditField.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AxialImagePanel = uipanel(app.UIFigure);
            app.AxialImagePanel.Title = 'Axial Image';
            app.UIAxes = uiaxes(app.AxialImagePanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.AxialImagePanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.AxialImagePanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.AxialImagePanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.FileEditFieldLabel = uilabel(app.ControlsPanel);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.ControlsPanel, 'text');
            app.InteractiveCheckBox = uicheckbox(app.ControlsPanel);
            app.InteractiveCheckBox.Text = 'Interactive';
            app.ThresholdEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.ThresholdEditField.ValueDisplayFormat = '%.2f';
            app.SkullSliderLabel = uilabel(app.ControlsPanel);
            app.SkullSliderLabel.Text = 'Skull';
            app.SkullSlider = uislider(app.ControlsPanel);
            app.SkullSlider.Limits = [0.9 1.5];
            app.SkullSlider.ValueChangedFcn = createCallbackFcn(app, @SkullSliderValueChanged, true);
            app.SkullSlider.Value = 1.35;
            app.StartButton = uibutton(app.ControlsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.LoadButton = uibutton(app.ControlsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SliceSliderLabel = uilabel(app.ControlsPanel);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.ControlsPanel);
            app.SliceSlider.Limits = [1 100];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.Enable = 'off';
            app.SliceSlider.Value = 50;
            app.SliceEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.SliceEditField.RoundFractionalValues = 'on';
            app.SliceEditField.ValueDisplayFormat = '%.0f';
            app.upButton = uibutton(app.ControlsPanel, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.upButton.Text = 'up';
            app.leftButton = uibutton(app.ControlsPanel, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.leftButton.Text = 'left';
            app.downButton = uibutton(app.ControlsPanel, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.downButton.Text = 'down';
            app.rightButton = uibutton(app.ControlsPanel, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.rightButton.Text = 'right';
            app.BrainSliderLabel = uilabel(app.ControlsPanel);
            app.BrainSliderLabel.Text = 'Brain';
            app.BrainSlider = uislider(app.ControlsPanel);
            app.BrainSlider.Limits = [0.2 2];
            app.BrainSlider.ValueChangedFcn = createCallbackFcn(app, @BrainSliderValueChanged, true);
            app.BrainSlider.Value = 0.4;
            app.EditField = uieditfield(app.ControlsPanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.1f';
            app.OrientationLabel = uilabel(app.ControlsPanel);
            app.OrientationLabel.Text = 'Orientation';
            app.FineTuneThresholdsLabel = uilabel(app.ControlsPanel);
            app.FineTuneThresholdsLabel.Text = 'Fine Tune Thresholds';
            app.SaveButton = uibutton(app.ControlsPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.MessagePanel = uipanel(app.UIFigure);
            app.MessagePanel.Title = 'Message';
            app.Label_2 = uilabel(app.MessagePanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SkSt2023

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end